classdef GlobalFittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlobalFittingAppUIFigure        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FitXmax                         matlab.ui.control.NumericEditField
        FitXmaxLabel                    matlab.ui.control.Label
        FitXmin                         matlab.ui.control.NumericEditField
        FitXminEditFieldLabel           matlab.ui.control.Label
        ClearDataButton                 matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        MODELEXAMPLESButton             matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        multistartTab                   matlab.ui.container.Tab
        StartPtsToRunDropDown           matlab.ui.control.DropDown
        StartPtsToRunDropDownLabel      matlab.ui.control.Label
        Hyperlink2                      matlab.ui.control.Hyperlink
        MaxTimeEditField                matlab.ui.control.NumericEditField
        MaxTimeEditFieldLabel           matlab.ui.control.Label
        UseParallelCheckBox             matlab.ui.control.CheckBox
        XTolEditField                   matlab.ui.control.NumericEditField
        XTolEditFieldLabel              matlab.ui.control.Label
        IterDisplayCheckBox_4           matlab.ui.control.CheckBox
        FunTolEditField                 matlab.ui.control.NumericEditField
        FunTolLabel                     matlab.ui.control.Label
        globalsearchTab                 matlab.ui.container.Tab
        StartPtsToRunDropDown_2         matlab.ui.control.DropDown
        StartPtsToRunDropDown_2Label    matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        PenaltyThresholdEditField       matlab.ui.control.NumericEditField
        PenaltyThresholdEditFieldLabel  matlab.ui.control.Label
        IterDisplayCheckBox_5           matlab.ui.control.CheckBox
        DistThresholdEditField          matlab.ui.control.NumericEditField
        DistThresholdLabel              matlab.ui.control.Label
        MaxTimeEditField_2              matlab.ui.control.NumericEditField
        MaxTimeEditField_2Label         matlab.ui.control.Label
        BasinRadiusEditField            matlab.ui.control.NumericEditField
        BasinRadiusLabel                matlab.ui.control.Label
        TrialPtsEditField               matlab.ui.control.NumericEditField
        TrialPtsEditFieldLabel          matlab.ui.control.Label
        XTolEditField_2                 matlab.ui.control.NumericEditField
        XTolEditField_2Label            matlab.ui.control.Label
        StagePtsEditField               matlab.ui.control.NumericEditField
        NumStage1PtsLabel               matlab.ui.control.Label
        FunTolEditField_2               matlab.ui.control.NumericEditField
        FunTolEditField_2Label          matlab.ui.control.Label
        fminconTab                      matlab.ui.container.Tab
        Hyperlink3_4                    matlab.ui.control.Hyperlink
        OptimTolEditField               matlab.ui.control.NumericEditField
        OptimTolEditFieldLabel          matlab.ui.control.Label
        StepTolEditField                matlab.ui.control.NumericEditField
        StepTolEditFieldLabel           matlab.ui.control.Label
        MaxIterEditField                matlab.ui.control.NumericEditField
        MaxIterLabel                    matlab.ui.control.Label
        TypicalXCheckBox                matlab.ui.control.CheckBox
        MaxFunEvalsEditField            matlab.ui.control.NumericEditField
        MaxFunEvalsLabel                matlab.ui.control.Label
        IterDisplayCheckBox             matlab.ui.control.CheckBox
        AlgoDropDown                    matlab.ui.control.DropDown
        AlgoLabel                       matlab.ui.control.Label
        fminuncTab                      matlab.ui.container.Tab
        Hyperlink3_3                    matlab.ui.control.Hyperlink
        OptimTolEditField_3             matlab.ui.control.NumericEditField
        OptimTolEditField_3Label        matlab.ui.control.Label
        StepTolEditField_3              matlab.ui.control.NumericEditField
        StepTolEditField_3Label         matlab.ui.control.Label
        TypicalXCheckBox_3              matlab.ui.control.CheckBox
        MaxIterEditField_3              matlab.ui.control.NumericEditField
        MaxIterEditField_3Label         matlab.ui.control.Label
        IterDisplayCheckBox_3           matlab.ui.control.CheckBox
        MaxFunEvalsEditField_3          matlab.ui.control.NumericEditField
        MaxFunEvalsEditField_3Label     matlab.ui.control.Label
        AlgoDropDown_3                  matlab.ui.control.DropDown
        AlgoDropDown_3Label             matlab.ui.control.Label
        lsqnonlinTab                    matlab.ui.container.Tab
        Hyperlink3_2                    matlab.ui.control.Hyperlink
        OptimTolEditField_2             matlab.ui.control.NumericEditField
        OptimTolEditField_2Label        matlab.ui.control.Label
        StepTolEditField_2              matlab.ui.control.NumericEditField
        StepTolEditField_2Label         matlab.ui.control.Label
        TypicalXCheckBox_2              matlab.ui.control.CheckBox
        MaxIterEditField_2              matlab.ui.control.NumericEditField
        MaxIterEditField_2Label         matlab.ui.control.Label
        IterDisplayCheckBox_2           matlab.ui.control.CheckBox
        MaxFunEvalsEditField_2          matlab.ui.control.NumericEditField
        MaxFunEvalsEditField_2Label     matlab.ui.control.Label
        AlgoDropDown_2                  matlab.ui.control.DropDown
        AlgoDropDown_2Label             matlab.ui.control.Label
        patternsearchTab                matlab.ui.container.Tab
        Hyperlink3                      matlab.ui.control.Hyperlink
        FunTolEditField_3               matlab.ui.control.NumericEditField
        FunTolEditField_3Label          matlab.ui.control.Label
        UseParallelCheckBox_2           matlab.ui.control.CheckBox
        StepTolEditField_4              matlab.ui.control.NumericEditField
        StepTolEditField_4Label         matlab.ui.control.Label
        MaxIterEditField_4              matlab.ui.control.NumericEditField
        MaxIterEditField_4Label         matlab.ui.control.Label
        MaxFunEvalsEditField_4          matlab.ui.control.NumericEditField
        MaxFunEvalsEditField_4Label     matlab.ui.control.Label
        IterDisplayCheckBox_6           matlab.ui.control.CheckBox
        AlgoDropDown_4                  matlab.ui.control.DropDown
        AlgoDropDown_4Label             matlab.ui.control.Label
        gaTab                           matlab.ui.container.Tab
        MaxTimeEditField_4              matlab.ui.control.NumericEditField
        MaxTimeEditField_4Label         matlab.ui.control.Label
        HybridFcnDropDown               matlab.ui.control.DropDown
        HybridFcnDropDownLabel          matlab.ui.control.Label
        FunTolEditField_4               matlab.ui.control.NumericEditField
        FunTolEditField_4Label          matlab.ui.control.Label
        Hyperlink3_5                    matlab.ui.control.Hyperlink
        MaxGensEditField                matlab.ui.control.NumericEditField
        MaxGensEditFieldLabel           matlab.ui.control.Label
        UseParallelCheckBox_3           matlab.ui.control.CheckBox
        IterDisplayCheckBox_7           matlab.ui.control.CheckBox
        particleswarmTab                matlab.ui.container.Tab
        SwarmSizeEditField              matlab.ui.control.NumericEditField
        SwarmSizeEditFieldLabel         matlab.ui.control.Label
        MaxTimeEditField_3              matlab.ui.control.NumericEditField
        MaxTimeEditField_3Label         matlab.ui.control.Label
        Hyperlink3_6                    matlab.ui.control.Hyperlink
        FunTolEditField_5               matlab.ui.control.NumericEditField
        FunTolEditField_5Label          matlab.ui.control.Label
        MaxIterEditField_6              matlab.ui.control.NumericEditField
        MaxIterEditField_6Label         matlab.ui.control.Label
        UseParallelCheckBox_4           matlab.ui.control.CheckBox
        IterDisplayCheckBox_8           matlab.ui.control.CheckBox
        HybridFcnDropDown_2             matlab.ui.control.DropDown
        HybridFcnDropDown_2Label        matlab.ui.control.Label
        simulannealbndTab               matlab.ui.container.Tab
        MaxFunEvalsEditField_5          matlab.ui.control.NumericEditField
        MaxFunEvalsEditField_5Label     matlab.ui.control.Label
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        annealingboltzButton            matlab.ui.control.RadioButton
        annealingfastButton             matlab.ui.control.RadioButton
        Hyperlink3_7                    matlab.ui.control.Hyperlink
        IterDisplayCheckBox_9           matlab.ui.control.CheckBox
        HybridFcnDropDown_3             matlab.ui.control.DropDown
        HybridFcnDropDown_3Label        matlab.ui.control.Label
        MaxTimeEditField_5              matlab.ui.control.NumericEditField
        MaxTimeEditField_5Label         matlab.ui.control.Label
        FunTolEditField_6               matlab.ui.control.NumericEditField
        FunTolEditField_6Label          matlab.ui.control.Label
        MaxIterEditField_7              matlab.ui.control.NumericEditField
        MaxIterEditField_7Label         matlab.ui.control.Label
        surrogateoptTab                 matlab.ui.container.Tab
        MaxTimeEditField_6              matlab.ui.control.NumericEditField
        MaxTimeEditField_6Label         matlab.ui.control.Label
        MinSampleDistEditField          matlab.ui.control.NumericEditField
        MinSampleDistEditFieldLabel     matlab.ui.control.Label
        ConstraintTolEditField          matlab.ui.control.NumericEditField
        ConstraintTolLabel              matlab.ui.control.Label
        Hyperlink3_8                    matlab.ui.control.Hyperlink
        UseParallelCheckBox_5           matlab.ui.control.CheckBox
        IterDisplayCheckBox_10          matlab.ui.control.CheckBox
        MaxFunEvalsEditField_6          matlab.ui.control.NumericEditField
        MaxFunEvalsEditField_6Label     matlab.ui.control.Label
        GotoGSTabButton                 matlab.ui.control.Button
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        OptimizationSettingsLabel       matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        MultiStartButton                matlab.ui.control.RadioButton
        GlobalSearchButton              matlab.ui.control.RadioButton
        OffButton                       matlab.ui.control.RadioButton
        GlobalSolversLabel              matlab.ui.control.Label
        FromWorkspaceButton             matlab.ui.control.Button
        FromFileButton                  matlab.ui.control.Button
        InputDataParamsLabel            matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        FittingTab                      matlab.ui.container.Tab
        YAxisButtonGroup                matlab.ui.container.ButtonGroup
        LogButton_2                     matlab.ui.control.RadioButton
        LinearButton_2                  matlab.ui.control.RadioButton
        XAxisButtonGroup                matlab.ui.container.ButtonGroup
        LogButton                       matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        EditModelYEditField             matlab.ui.control.EditField
        EditModelYEditFieldLabel        matlab.ui.control.Label
        StartfittingButton              matlab.ui.control.Button
        ModelPresetsDropDown            matlab.ui.control.DropDown
        ModelPresetsDropDownLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        SolTable                        matlab.ui.control.Table
        ParamTable                      matlab.ui.control.Table
        OptimizationParametersLabel     matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        MathematicalModelLabel          matlab.ui.control.Label
        OutputFitResults                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        YData % Holds full 2D dataset
        XData % Holds XData values - time or wavelength
        Table % Holds tabular data

        %         fitFunc % Holds specified fitscript depending on model chosen
        ft % Holds fittype of current model chosen

        Params % Holds string array of params in equation until changed
        temp % Holds original table data
        Opts % Property to hold selected optimoptions from chosen solver
        numP % Hold number of parameters of current equation
        TypicalX % Boolean to hold if we use typical x or not
        FileName % Hold the filename of the currently loaded data
        allSolvers % Holds all solver items

        GS % Holds GlobalSearch
        MS % Holds MultiStart
        vals % Holds the fit results
        FitResiduals % Holds the fit residuals

        XaxisStyle  % For plotting in linear or log scale
        YaxisStyle  % For plotting in linear or log scale

        XfitMin     % User defined minimum for fitting range
        XfitMax     % User defined maximum for fitting range

        cfitResult  % For exporting the cfit objects of the fit results
    end

    methods (Access = private)

        function params = getParams(app)
            % % FIRST ATTEMPT
            % % GET RID OF X FROM PARAMS LIST
            % model = app.EditModelYEditField.Value;
            %
            % str = erase(model," "); % Remove whitespace
            % cut = regexprep(str,'[\+,\-,\*,\^,\/,\\,\(,\),\[,\]]',','); % Get rid of mathematical operators
            % cut = strrep(cut,"exp,",''); % Get rid of matlab functions
            % cut = strrep(cut,"x,",''); % Get rid of independent var.
            %
            % tempp = strsplit(cut,','); % Temp parameters
            %
            % params = unique(tempp);
            %
            % while true
            %     if isnan(str2double(params(1)))
            %         break % Break if character vector is present
            %     else
            %         params(1) = []; % Get rid of numbers
            %     end
            % end
            %
            % app.Params = params;

            % Use oldparams to check if they have changed
            oldParams = app.Params;
            oldNum = app.numP;

            % Use fittype function to automatically get into params form
            eq = fittype(app.EditModelYEditField.Value,'independent',{'x'});
            cnames = coeffnames(eq);
            pnames = probnames(eq);
            paramCell = [pnames;cnames];

            % Extract new params in string form and get length
            params = string(paramCell);
            num = length(params);

            % If you have same number of params, there is a chance we have
            % the exact same parameters and only numerical constants were
            % added to the model eqn
            if oldNum == num
                % If the exact same params have not changed then do
                % nothing. If not same, continue with code
                if strcmp(params,oldParams)
                    return
                end
            end

            % Clear table data and reapply default values
            app.ParamTable.Data = [];
            app.ParamTable.RowName = params;

            % Need to apply each row
            for i = 1:num
                app.ParamTable.Data = [app.ParamTable.Data;[1,-inf,inf,false]];
            end

            % MIGHT NOT NEED THIS ANYMORE
            app.temp = app.ParamTable.Data;

            % Reapply properties to hold current param properties
            app.Params = params;
            app.numP = num;
        end

        function switchTab(app,selectedTab)
            % We use switchTab as a general function so use selectedTab as
            % a passed parameter
            solver = selectedTab.Title;
            nump = app.numP;

            switch selectedTab
                case app.fminconTab
                    algo = app.AlgoDropDown.Value;
                    maxeval = app.MaxFunEvalsEditField.Value;
                    maxiter = app.MaxIterEditField.Value;
                    steptol = app.StepTolEditField.Value;
                    optimtol = app.OptimTolEditField.Value;
                    display = app.IterDisplayCheckBox.Value;

                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"Algorithm",algo,"MaxFunctionEvaluations",maxeval,"MaxIterations",maxiter,"StepTolerance",steptol,"OptimalityTolerance",optimtol,"Display",display);
                case app.fminuncTab
                    algo = app.AlgoDropDown_3.Value;
                    maxeval = app.MaxFunEvalsEditField_3.Value;
                    maxiter = app.MaxIterEditField_3.Value;
                    steptol = app.StepTolEditField_3.Value;
                    optimtol = app.OptimTolEditField_3.Value;
                    display = app.IterDisplayCheckBox_3.Value;

                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"Algorithm",algo,"MaxFunctionEvaluations",maxeval,"MaxIterations",maxiter,"StepTolerance",steptol,"OptimalityTolerance",optimtol,"Display",display);
                case app.lsqnonlinTab
                    algo = app.AlgoDropDown_2.Value;
                    maxeval = app.MaxFunEvalsEditField_2.Value;
                    maxiter = app.MaxIterEditField_2.Value;
                    steptol = app.StepTolEditField_2.Value;
                    optimtol = app.OptimTolEditField_2.Value;
                    display = app.IterDisplayCheckBox_2.Value;

                    % Set to default values on startup
                    if maxeval == 1
                        if strcmp(algo,'trust-region-reflective')
                            maxeval = 100*nump;
                        else
                            maxeval = 200*nump;
                        end
                        app.MaxFunEvalsEditField_2.Value = maxeval;
                    end

                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"Algorithm",algo,"MaxFunctionEvaluations",maxeval,"MaxIterations",maxiter,"StepTolerance",steptol,"OptimalityTolerance",optimtol,"Display",display);
                case app.patternsearchTab
                    algo = app.AlgoDropDown_4.Value;
                    maxeval = app.MaxFunEvalsEditField_4.Value;
                    maxiter = app.MaxIterEditField_4.Value;
                    steptol = app.StepTolEditField_4.Value;
                    funtol = app.FunTolEditField_3.Value;
                    display = app.IterDisplayCheckBox_6.Value;
                    parallel = app.UseParallelCheckBox_2.Value;

                    % Set to default values on startup
                    if maxeval == 1 || maxiter == 1
                        maxeval = 2000*nump;
                        app.MaxFunEvalsEditField_4.Value = maxeval;

                        maxiter = 100*nump;
                        app.MaxIterEditField_4.Value = maxiter;
                    end

                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"Algorithm",algo,"MaxFunctionEvaluations",maxeval,"MaxIterations",maxiter,"StepTolerance",steptol,"FunctionTolerance",funtol,"Display",display,"UseParallel",parallel);
                case app.gaTab
                    maxgen = app.MaxGensEditField.Value;
                    maxtime = app.MaxTimeEditField_4.Value;
                    funtol = app.FunTolEditField_4.Value;
                    display = app.IterDisplayCheckBox_7.Value;
                    parallel = app.UseParallelCheckBox_3.Value;
                    hybrid = app.HybridFcnDropDown.Value;

                    % Set to default values on startup
                    if maxgen == 1
                        maxgen = 100*nump;
                        app.MaxGensEditField.Value = maxgen;
                    end

                    if strcmp(hybrid,'none')
                        hybrid = [];
                    end

                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"MaxGenerations",maxgen,"MaxTime",maxtime,"FunctionTolerance",funtol,"HybridFcn",hybrid,"Display",display,"UseParallel",parallel);
                case app.particleswarmTab
                    swarmsz = app.SwarmSizeEditField.Value;
                    maxiter = app.MaxIterEditField_6.Value;
                    maxtime = app.MaxTimeEditField_3.Value;
                    funtol = app.FunTolEditField_5.Value;
                    display = app.IterDisplayCheckBox_8.Value;
                    parallel = app.UseParallelCheckBox_4.Value;
                    hybrid = app.HybridFcnDropDown_2.Value;

                    % Set to default values on startup
                    if swarmsz == 1 || maxiter == 1
                        swarmsz = min(100,10*nump);
                        app.SwarmSizeEditField.Value = swarmsz;

                        maxiter = 200*nump;
                        app.MaxIterEditField_6.Value = maxiter;
                    end

                    if strcmp(hybrid,'none')
                        hybrid = [];
                    end

                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"MaxIterations",maxiter,"MaxTime",maxtime,"FunctionTolerance",funtol,"HybridFcn",hybrid,"SwarmSize",swarmsz,"Display",display,"UseParallel",parallel);
                case app.simulannealbndTab
                    maxeval = app.MaxFunEvalsEditField_5.Value;
                    maxiter = app.MaxIterEditField_7.Value;
                    maxtime = app.MaxTimeEditField_5.Value;
                    funtol = app.FunTolEditField_6.Value;
                    display = app.IterDisplayCheckBox_9.Value;
                    annealfcn = app.ButtonGroup_3.SelectedObject.Text;
                    hybrid = app.HybridFcnDropDown_2.Value;

                    % Set to default values on startup
                    if maxeval == 1
                        maxeval = 3000*nump;
                        app.MaxFunEvalsEditField_5.Value = maxeval;
                    end

                    if strcmp(hybrid,'none')
                        hybrid = [];
                    end

                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"MaxIterations",maxiter,"MaxTime",maxtime,"FunctionTolerance",funtol,"HybridFcn",hybrid,"AnnealingFcn",annealfcn,"Display",display);
                case app.surrogateoptTab
                    maxeval= app.MaxFunEvalsEditField_6.Value;
                    maxtime = app.MaxTimeEditField_6.Value;
                    contol = app.ConstraintTolEditField.Value;
                    display = app.IterDisplayCheckBox_10.Value;
                    mindist = app.MinSampleDistEditField.Value;
                    parallel = app.UseParallelCheckBox_5.Value;

                    % Set to default values on startup
                    if maxeval == 1
                        maxeval =  max(200,50*nump);
                        app.MaxFunEvalsEditField_6.Value = maxeval;
                    end
                    display = dispCheck(app,display); % Check iter display

                    opts = optimoptions(solver,"MaxFunctionEvaluations",maxeval,"MaxTime",maxtime,"ConstraintTolerance",contol,"MinSampleDistance",mindist,"Display",display,"UseParallel",parallel);
                    %                 case app.gamultiobjTab
                    %
                    %                 case app.paretosearchTab

                case app.multistartTab
                    funtol = app.FunTolEditField.Value;
                    xtol = app.XTolEditField.Value;
                    maxtime = app.MaxTimeEditField.Value;
                    display = app.IterDisplayCheckBox_4.Value;
                    parallel = app.UseParallelCheckBox.Value;
                    startptsrun = app.StartPtsToRunDropDown.Value;

                    display = dispCheck(app,display); % Check iter display

                    app.MS = MultiStart(app.MS,"Display",display,"FunctionTolerance",funtol,"MaxTime",maxtime,"XTolerance",xtol,"StartPointsToRun",startptsrun,"UseParallel",parallel);
                    return
                case app.globalsearchTab
                    funtol = app.FunTolEditField_2.Value;
                    xtol = app.XTolEditField_2.Value;
                    basinrad = app.BasinRadiusEditField.Value;
                    disthresh = app.DistThresholdEditField.Value;
                    penaltythresh = app.PenaltyThresholdEditField.Value;
                    stagepts = app.StagePtsEditField.Value;
                    trialpts = app.TrialPtsEditField.Value;
                    maxtime = app.MaxTimeEditField_2.Value;
                    display = app.IterDisplayCheckBox_5.Value;
                    startptsrun = app.StartPtsToRunDropDown_2.Value;

                    display = dispCheck(app,display); % Check iter display

                    app.GS = GlobalSearch(app.GS,"BasinRadiusFactor",basinrad,"Display",display,"DistanceThresholdFactor",disthresh,"FunctionTolerance",funtol,"MaxTime",maxtime,"XTolerance",xtol,"PenaltyThresholdFactor",penaltythresh,"NumStageOnePoints",stagepts,"StartPointsToRun",startptsrun,"NumTrialPoints",trialpts);
                    return

            end
            app.Opts = opts;
        end

        %         function updateRadio(app,newSolver)
        %             % Highlight clickable radio button to take you to solver options page
        %             tf = false(3,1);
        %             for i = 1:3
        %                 tf(i,1) = strcmp(newSolver,app.ButtonGroup.Children(i,1).Text);
        %             end
        %             % tf = flip(tf); % Stack order must be flipped to tab to elements properly so we flip in code.
        %
        %             % Now assign highlight and reset others
        %             app.ButtonGroup.SelectedObject.FontColor = [0.00,0.45,0.74];
        %             nonSelected = app.ButtonGroup.Children(~tf,1);
        %             nonSelected(1).FontColor = [0,0,0];
        %             nonSelected(2).FontColor = [0,0,0];
        %         end

        %         function F = myfunc(Beta,fun)
        %             global n FUN X start y
        %             params = start;
        %             f0 = fun;
        %             Y = y;
        %             F=[];
        %             for i=1:n
        %                 F=[F;FUN(Beta,X)-Y(:,i)];
        %             end
        %         end


        function display = dispCheck(~,checkbox)
            % Display checkbox needs to be set to iter
            if strcmp(checkbox,"on")
                display = "iter";
            else
                display = "none";
            end
        end

        function table = LoadDataFcn(app, Data)
            Data = renamevars(Data,1,"XData");
            table = outerjoin(app.Table, Data,'MergeKeys',true, 'Keys',"XData");
            TIME = table{:,1};
            app.YData = table{:,2:end};

            if iscell(TIME)
                strs = strsplit(TIME{1,1});
                type = strs{1,2};

                switch type
                    case 'ps'
                        multiplier = 1e-12;
                    case 'ns'
                        multiplier = 1e-9;
                    case 'us'
                        multiplier = 1e-6;
                end

                strvals = cellfun(@(x) strsplit(x), TIME, 'UniformOutput', false);
                strvals = vertcat(strvals{:}); % To remove nesting of cell array strVals
                app.vals = str2double(strvals(:,1)); % Turn into double array

                app.vals = app.vals * multiplier; % Make sure values are in proper scale;

                app.XData = app.vals; % combine time columns
                % try merge for tables??
            else
                app.XData = TIME;
                %                     app.XData = str2double(TIME); %DOUBLE CHECK THIS WORKS?+++++++++++++++++
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Check if MATLAB addons are installed
                matlab.addons.isAddonEnabled('Optimization Toolbox');
                matlab.addons.isAddonEnabled('Global Optimization Toolbox');
                matlab.addons.isAddonEnabled('Symbolic Math Toolbox');
                matlab.addons.isAddonEnabled('Curve Fitting Toolbox');
            catch ME
                disp(ME);
                app.LoadCreateError.Text = "Please ensure the 'Optimization', 'Global Optimization', 'Symbolic Math' and 'Curve Fitting' Toolboxes are installed to continue";
                return;
            end

            % Run default setup with default model
            ModelPresetsDropDownValueChanged(app);

            % Update GUI elements to proper format
            app.ParamTable.ColumnFormat = {'numeric','numeric','numeric','logical'};
            app.SolTable.ColumnFormat = {'char','char'};
            app.SolTable.Data = strings(2,1);
            app.SolTable.Visible = "off";

            app.XaxisStyle = app.XAxisButtonGroup.SelectedObject.Text;
            app.YaxisStyle = app.YAxisButtonGroup.SelectedObject.Text;

            % Initialize app properties
            app.allSolvers = {'auto','fmincon','lsqnonlin','patternsearch','ga','particleswarm','simulannealbnd'};%,'fminunc','surrogateopt','gamultiobj','paretosearch'}; DO NOT NEED MULTIOBJ SOLVERS UNLESS WE ADD AN OBJ. FUN. EDITFIELD
            %fminunc is not a constrained solver and therefore also cannot
            %be used. surrogateopt is also not compatible

            % Initialize our GlobalSolver (GS) objects
            app.GS = GlobalSearch;
            app.MS = MultiStart;

            app.XData = [];
            app.YData = [];
            app.Table = table(0,'VariableNames',"XData");
        end

        % Button pushed function: FromFileButton
        function FromFileButtonPushed(app, event)
            % Find specified data file and create absolute path filename
            [file,path] = uigetfile({'*.xlsx;*.xls';'*.csv;*.txt'}, 'Select data file(s) to import.', 'MultiSelect','on'); % file is cell array of character vectors when multiselet
            if ischar(file) == 1
                file = cellstr(file);
            end
            if isequal(file, 0) % The filename equals zero when cancel is pressed
                return;
            end

            for k = 1:length(file)
                absoluteName = [path,file{k}];

                if isa(file,"double")
                    return
                    %             elseif strcmp(file{k},app.FileName) % if file is identical to existing app.Filename
                    %                 cla(app.UIAxes); % Clears and re-plots
                else
                    %                  % Clear Data
                    %                 app.YData = [];
                    %                 app.XData = [];

                    %                 cla(app.UIAxes);

                    % Check if there are more than one sheet in the file
                    [~,~,ext] = fileparts(absoluteName);% Check the file extension
                    errorcount = 0;
                    if strcmp(ext, ".xls")||strcmp(ext, ".xlsx")
                        sheets = sheetnames(absoluteName); % .xls and .xlsx only (excel files only)

                        for j = 1:size(sheets) % Load each sheet
                            try % set num header lines to num non numeric in first col
                                Data = readtable(absoluteName,"Sheet",(sheets(j)),'VariableNamingRule','preserve', 'NumHeaderLines', 1, "MissingRule", 'fill'); % Load specified sheet
                                table = LoadDataFcn(app, Data);
                                app.Table = table;
                            catch
                                errorcount = errorcount +1;
                                continue
                            end
                        end

                        %                     if length(sheets) > 1
                        % %                         Index = [find(contains(lower(sheets),'kinetic','IgnoreCase',false)), ...
                        % %                             find(contains(lower(sheets),'spectra','IgnoreCase',false))];
                        %
                        %                         if length(Index) > 1 % If there are multiple sheets with kinetic/spectra in the name, choose which to load
                        %                             answer = uiconfirm(app.GlobalFittingAppUIFigure,"There are more than one kinetic/spectra worksheets","Choose sheets to load","Options",sheets(Index));
                        %                             sheetInd = contains(sheets,answer);
                        %                             Data = readtable(absoluteName,"Sheet",(sheets(sheetInd)),'VariableNamingRule','preserve'); % Load specified sheet
                        %                         else
                        %                             Data = readtable(absoluteName,"Sheet",(sheets(Index)),'VariableNamingRule','preserve'); % Load the only kinetics sheet found
                        %                         end
                        %                     end
                    else
                        try
                            Data = readtable(absoluteName,'VariableNamingRule','preserve', 'NumHeaderLines', 1);
                            table = LoadDataFcn(app, Data);
                            app.Table = table;
                        catch
                            errorcount = errorcount +1;
                            continue
                        end
                    end


                    % Enable Global Optimization Button
                    app.StartfittingButton.Enable = "on";

                    % Save filname for later if same data is loaded
                    app.FileName = file;
                end
            end

            if errorcount > 0
                disp([errorcount, " file(s)/sheets(s) were unable to be imported"])
            end

            % Set fit range to full span
            app.FitXmin.Value = min(app.XData);
            app.FitXmax.Value = max(app.XData);

            % Plot data
            scatter(app.UIAxes,app.XData,app.YData,"Marker",'.','SizeData',18)
            set(app.UIAxes,'XScale',app.XaxisStyle,'YScale',app.YaxisStyle)
            xlim(app.UIAxes,[app.FitXmin.Value app.FitXmax.Value])
        end

        % Button pushed function: FromWorkspaceButton
        function FromWorkspaceButtonPushed(app, event)

            WorkSpace = evalin('base','whos');
            [indx,tf] = listdlg('PromptString',{'Select data from the workspace'}, 'SelectionMode','multiple','ListString',{WorkSpace.name});

            if tf == 0 % Nothing Selected
                return
            end

            errorcount = 0;

            for k = indx

                Files = evalin('base',WorkSpace(k).name);
                if ~isa(Files, "table")
                    Files = array2table(Files);
                end

                try
                    table = LoadDataFcn(app, Files);
                catch
                    errorcount = errorcount +1;
                    continue
                end

                app.Table = table;  % Load data
            end

            if errorcount > 0
                disp([errorcount, " file(s)/sheets(s) were unable to be imported"])
            end

            % Enable Global Optimization Button
            app.StartfittingButton.Enable = "on";

            % Set fit range to full span
            app.FitXmin.Value = min(app.XData);
            app.FitXmax.Value = max(app.XData);

            % Plot data
            scatter(app.UIAxes,app.XData,app.YData,"Marker",'.','SizeData',18)
            set(app.UIAxes,'XScale',app.XaxisStyle,'YScale',app.YaxisStyle)
            xlim(app.UIAxes,[app.FitXmin.Value app.FitXmax.Value])
        end

        % Value changed function: ModelPresetsDropDown
        function ModelPresetsDropDownValueChanged(app, event)
            value = app.ModelPresetsDropDown.Value;

            switch value
                case "Monoexponential"
                    app.EditModelYEditField.Value = "A1*exp(-C1*x) + Y0";

                case "Biexponential"
                    app.EditModelYEditField.Value = "A1*exp(-C1*x) + A2*exp(-C2*x) + Y0";

                case "Triexponential"
                    app.EditModelYEditField.Value = "A1*exp(-C1*x) + A2*exp(-C2*x) + A3*exp(-C3*x) + Y0";

                case "Power Law"
                    app.EditModelYEditField.Value = "a0./exp(n*log(1+b*x)) + Y0";

                case "Monoexponential + Power Law"
                    app.EditModelYEditField.Value = "A1*exp(-C1*x) + a0./exp(n*log(1+b*x)) + Y0";
            end
            EditModelYEditFieldValueChanged(app);
        end

        % Button pushed function: StartfittingButton
        function StartfittingButtonPushed(app, event)
            %% Global minimization
            cla(app.UIAxes);
            Func = app.EditModelYEditField.Value;

            % Determine data
            x = app.XData(app.FitXmin.Value < app.XData & app.XData < app.FitXmax.Value);   % User set fitting range
            ydata = app.YData(app.FitXmin.Value < app.XData & app.XData < app.FitXmax.Value,:);

            N = size(ydata,2);

            % Turn x and y data into stacked (N*L) x 1 column vectors
            % And create dsid for number of distinct non-shared params
            X = repmat(x,N,1);
            Y = ydata(:);
            dsid = repelem((1:N)',length(x)); %#ok<NASGU>

            % From fittype equation definition, we can gather all params
            eq = fittype(Func,'independent',{'x'});
            cnames = coeffnames(eq);
            pnames = probnames(eq);
            paramCell = [pnames;cnames];
            params = string(paramCell);
            nump = length(params);
            app.numP = nump;

            % Turn fittype into real function
            fchar = evalc('eq');
            fchar  = strrep(fchar,'eq','@');
            fchar  = strrep(fchar ,'=','');
            fchar (1:31) = [];
            fchar = fchar(~isspace(fchar));
            fitstr = string(fchar);
            fitFunc = str2func(fitstr);

            % Automatically sorted params with fittype method so use index
            sz = size(params); % Number of parameters, not including x
            var = cell(sz); % Create empty cell aray to create optimvars

            % Bounds and shared params boolean
            LowerBound = app.ParamTable.Data(:,2);
            UpperBound = app.ParamTable.Data(:,3);
            shared = app.ParamTable.Data(:,4);

            % Convert vars into optimvars and count total number of params (including
            % distinct + shared)
            f0String = fitstr; %char(fitstr); % Recreate fitFunc in str form to edit below
            totNum = 0; % Initialize total num
            totInd = 0; % Initialize index
            for i = 1:nump
                if shared(i,1) == 1 % If shared then only 1 variable among all datasets
                    var{i} = optimvar(params(i,1),[1 1],'LowerBound',LowerBound(i,1),'UpperBound',UpperBound(i,1));
                    totInd = [totInd, 0, totInd(end,end) + 1];
                    totNum = totNum + 1;
                else % If not shared, then create N distinct parameters for the 1 optimvar for N datasets
                    var{i} = optimvar(params(i,1),[N 1],'LowerBound',LowerBound(i,1),'UpperBound',UpperBound(i,1)); % Convert each into optimvar
                    totInd = [totInd, 0, totInd(end,end)+1:totInd(end,end)+N];
                    totNum = totNum + N;

                    % Replace non-shared variables with (dsid) indexing
                    str2keep = strcat(extractBefore(f0String,')'),')');
                    str2edit = extractAfter(f0String,')');

                    old = char(params(i,1));    % Definet the parameter to look for and add (dsid)
                    str2edit = insertAfter(str2edit,old,'(dsid)');  %  Add dsid programmatically to all occurances in function.


                    f0String = strcat(str2keep,str2edit); % Rebuild function string.
                end
            end

            % Now fix operations to be elementwise.
            f0String = strrep(f0String,'/','./');
            f0String = strrep(f0String,'*','.*');
            f0String = strrep(f0String,'^','.^');
            f0String = strrep(f0String,'..','.');    % Avoid double .. if the elementwise operator needs to be specified in the Model text box. Output final string.

            totInd(1:2) = []; %Erase padded zeros at beginning

            % Turn our dsid string into the function we want

            % WHY DOESN'T STR2FUNC WORK HERE?
            % f0String = f0String(~isspace(f0String));
            % str = string(f0String);
            % f0 = str2func(str);

            f0 = eval(f0String);

            % Find index groups of each multidimensional or 1D shared optim param
            ne0 = find(totInd~=0);                              % Nonzero Elements
            ix0 = unique([ne0(1) ne0(diff([0 ne0])>1)]);        % Segment Start Indices
            ix1 = ne0([find(diff([0 ne0])>1)-1 length(ne0)]);   % Segment End Indices
            L = length(ix0);
            section = cell(L,1);
            for k1 = 1:L
                section{k1} = [num2str(totInd(ix0(k1))),':',num2str(totInd(ix1(k1)))];
            end

            % Might have included poor model operators/functions/variables
            % So include poper error handling
            try
                if N == 1
                    y = feval(fitFunc,var{:},x);
                else
                    y = f0(var{:},X);% RG: Why do we need to offset the X values? y = f0(var{:},X-app.FitXmin.Value)
                    % y(1,i) = feval(fitFunc,var{:}(1,i),x);
                    % y(:,1:end) = SimpleFunc(cParam(1:end),TestX)

                end
            catch e
                disp(e) % Display original error in command window
                switch e.identifier % Display UIAlert depending on error
                    case "optim_problemdef:operators:InvalidPower"
                        uialert(app.GlobalFittingAppUIFigure,"Error in nonscalar coefficients. Please get rid of any operators in the form of a^n or a.^n and replace with exp(n*log(a))","Error in Model Equation","Icon","error");
                    case "optim_problemdef:OptimizationNumeric:InvalidComplex"
                        uialert(app.GlobalFittingAppUIFigure,"Error in nonscalar coefficients. Please remove any complex values of the form i or j","Error in Model Equation","Icon","error");
                end
                return
            end

            %             % Define a function that takes a parameter vector and calls
            %             % f0 with separate parameters
            %             vecFunc = strings(1,sz(1));
            %             vecFunc(1,1) = "@(params,X) f0(params(" + section{1} + "),params(";
            %
            %             % Create string array on number of distinct parameters per optim var
            %             for i = 2:nump
            %                 if i == nump
            %                     vecFunc(i) = section{i};
            %                 else
            %                     vecFunc(i) = section{i}+"),params(";
            %                 end
            %             end
            %             vecFunc(end+1) = "),X)"; % Cap off array with X data
            %
            %             % Join the strings into 1x1 string
            %             % f1 = strjoin(["@(params,X)", " f0(params(",[eval('section{1}')],"),params(",[eval('section{2}')],"),params(",[eval('section{3}')],"),params(",[eval('section{4}')],"),params(",[eval('section{5}')],"),X)"]);
            %             % f1 = strjoin(["@(params,X)", " f0(params(",section{1},"),params(",section{2},"),params(",section{3},"),params(",section{4},"),params(",section{5},"),X)"]);
            %             f1 = strjoin(vecFunc);
            %             fchar = char(f1); % Turn into character array
            %
            %             % Get rid of white space and turn into function
            %             fchar = fchar(~isspace(fchar));
            %             str = string(fchar);
            %             f1 = str2func(str);

            % Extract initial guesses
            InitialGuess = app.ParamTable.Data(:,1);

            IG = cell(1,nump);

            % Need to determine initial guesses based on number of distinct params
            for i = 1:nump
                ids = eval(section{i});
                IG{i} = repmat(InitialGuess(i,1),1,ids(end)-ids(1) + 1);
                x0.(params(i,1)) = IG{i}; % Create an initialGuess structure with all params names as field names
            end

            % Create problem and objective function
            obj = fcn2optimexpr(@(y,ydata)sum((y-ydata).^2,'all'),y,Y);
            prob = optimproblem("Objective",obj);

            % Determine solver for problem object
            solver = app.SolverDropDown.Value;
            multi = app.ButtonGroup_2.SelectedObject.Text;

            % Turn on Lamp to let user know solver is running
            app.Lamp.Enable = "on";
            app.Label.Text = "Solver Running";
            app.Lamp.Color = [0.00,1.00,0.00];
            pause(0.0000000000000001); % Delay to allow lamp update

            % Typical X matrix only for certain solvers
            %             InitCell = struct2cell(x0);
            InitMat = cell2mat(IG)';

            % Determine optimoptions
            opts = app.Opts;
            if app.TypicalX == 1
                % We determine TypicalX here since InitMat is already
                % calculated once and not held as a property
                opts = optimoptions(opts,"TypicalX",InitMat,"ScaleProblem",true);
            end

            if strcmp(solver,"auto")
                [sol,app.FitResiduals,~,~,~] = solve(prob,x0);
            elseif ~strcmp(multi,"Off")
                % Select proper global solver with specific properties
                if strcmp(multi,"Global Search")
                    gs = app.GS;
                else
                    gs = app.MS;
                end

                [sol,app.FitResiduals,~,~,~] = solve(prob,x0,gs,"Solver",solver,"Options",opts);
            else
                [sol,app.FitResiduals,~,~,~] = solve(prob,x0,"Solver",solver,"Options",opts);
            end

            % Turn off Lamp to let user know solver is done
            app.Lamp.Enable = "off";
            app.Label.Text = "";
            app.Lamp.Color = [1.00,0.00,0.00];

            % Turn values into cell
            app.vals = struct2cell(sol);

            % Extract all solution parameters into equal size matrix and plot against original data
            fns = fieldnames(sol);
            paramgs = zeros(nump,N);
            for i = 1:nump
                solval = sol.(fns{i});
                if size(solval,1) > 1
                    paramgs(i,:) = solval;
                else
                    paramgs(i,:) = repmat(solval,1,N);
                end
            end

            % Create format spec outside of loop
            formatSpec = '%.4f, ';

            % Apply data to table
            app.SolTable.Data = strings(2,1);
            for i = 1:nump
                column1 = convertCharsToStrings(fns{i});

                formatVals = num2str((app.vals{i})',formatSpec);
                formatVals(end) = []; % Get rid of last comma in string

                column2 = convertCharsToStrings(formatVals);
                app.SolTable.Data(i,1) = column1;
                app.SolTable.Data(i,2) = column2;
            end
            % Add fit residuals
            app.SolTable.Data(i+1,1) = 'Residuals';
            app.SolTable.Data(i+1,2) = app.FitResiduals;

            % Populate solution table and plot
            app.SolTable.Visible = "on";
            cdata = lines(N); % Create distinct colouring for each set, different from the data points

            % Plot solution data with original. Also prepare cfit objects
            % for export
            app.cfitResult = cell([]);
            for i = 1:N
                % Replot original data - use scatter markers with line call
                line(app.UIAxes,app.XData,app.YData(:,i),'LineStyle','none','Marker','o','MarkerSize',4,'MarkerFaceColor',cdata(i,:),'MarkerEdgeColor',[0 0 0]);

                % Plot solutions
                sol = num2cell((paramgs(:,i))');
                ydata = fitFunc(sol{:},x);
                line(app.UIAxes,x,ydata,'LineWidth',2,'Color',cdata(i,:));

                % Generate cfit object
                app.cfitResult{i} = cfit(eq,sol{:});
            end
           
                       set(app.UIAxes,'XScale',app.XaxisStyle,'YScale',app.YaxisStyle)
            xlim(app.UIAxes,[app.FitXmin.Value app.FitXmax.Value])
            
            % Prepare for exporting results
            app.OutputFitResults.Enable = "on"; % Make output fit button enabled.
        end

        % Value changed function: EditModelYEditField
        function EditModelYEditFieldValueChanged(app, event)
            % After model is changed, change parameters dropwdown + table
            getParams(app);

            Func = app.EditModelYEditField.Value;
            % From fittype equation definition, we can gather all params
            eq = fittype(Func,'independent',{'x'});
            cnames = coeffnames(eq);
            pnames = probnames(eq);
            paramCell = [pnames;cnames];
            params = string(paramCell);
            nump = length(params);
            app.numP = nump;

            % Turn fittype into real function
            fchar = evalc('eq');
            fchar  = strrep(fchar,'eq','@');
            fchar  = strrep(fchar ,'=','');
            fchar (1:31) = [];
            fchar = fchar(~isspace(fchar));
            str = string(fchar);
            fitFunc = str2func(str);

            S = sym(fitFunc);
            chr = latex(S);

            app.Label_2.Text = string(['$$',chr,'$$']);
        end

        % Cell edit callback: ParamTable
        function ParamTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            fix = strcmp(app.ParamTable.RowName(indices(1),1),app.Params);
            app.temp(fix) = newData;
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            value = app.SolverDropDown.Value;

            if strcmp(value,'auto')
                app.TabGroup2.Visible = "off";
                return
            else
                app.TabGroup2.Visible = "on";
            end

            % Use solver name to switch tab
            newTab = "app." + value + "Tab";
            app.TabGroup2.SelectedTab = eval(newTab);

            % Update options with switchTab
            selectedTab = app.TabGroup2.SelectedTab;
            switchTab(app,selectedTab);
        end

        % Button pushed function: MODELEXAMPLESButton
        function MODELEXAMPLESButtonPushed(app, event)
            f = uifigure("Name","Model Examples");
            g = uigridlayout(f,[4,3]);
            g.RowHeight = {'fit','1x','fit','1x'};
            g.ColumnWidth = {'fit','1x','1x'};

            % Path for arrow image
            path = "GlobalFitting_Resources\right-arrow-icon-114837-11.png";

            % If parameters are used as exponents. We must switch to new
            % format
            % Bad Case Example 1
            fitFunc1 = @(Y0,a0,b,n,x)a0/(1+b*x)^n+Y0;
            S1 = sym(fitFunc1);
            chr1 = latex(S1);

            % Parameters as Exponents Examples Header
            l01 = uilabel(g,"HorizontalAlignment",'center','VerticalAlignment',"bottom");
            l01.Text = "When using parameters as exponenets:";
            l01.Layout.Row = 1;
            l01.Layout.Column = 1;

            % Express display in uilabel
            l1 = uilabel(g,"HorizontalAlignment",'center');
            l1.Interpreter = 'latex';
            l1.Text = string(['$$',chr1,'$$']);
            l1.Layout.Row = 2;
            l1.Layout.Column = 1;

            % Create arrow image
            i1 = uiimage(g,"ImageSource",path);
            i1.Layout.Row = 2;
            i1.Layout.Column = 2;

            % Good Case Example 1
            fitFunc2 = @(Y0,a0,b,n,x)a0/exp(n*log((b*x+1)))+Y0;
            S2 = sym(fitFunc2);
            chr2 = latex(S2);

            % Express display in uilabel
            l2 = uilabel(g,"HorizontalAlignment",'center');
            l2.Interpreter = 'latex';
            l2.Text = string(['$$',chr2,'$$']); % Get into display mode ($$)
            l2.Layout.Row = 2;
            l2.Layout.Column = 3;

            % -------------------------------------------------------------
            % Complex Numbers Examples Header
            l02 = uilabel(g,"HorizontalAlignment",'center');
            l02.Text = "When using complex functions (i or j) as parameters:";
            l02.Layout.Row = 3;
            l02.Layout.Column = 1;

            % If parameters are used as matlab functions. We must switch to new
            % format
            % Bad Case Example 1
            fitFunc3 = @(a,i,y,x) a*x + i*y;
            S3 = sym(fitFunc3);
            chr3 = latex(S3);

            % Express display in uilabel
            l1 = uilabel(g,"HorizontalAlignment",'center');
            l1.Interpreter = 'latex';
            l1.Text = string(['$$',chr3,'$$']);
            l1.Layout.Row = 4;
            l1.Layout.Column = 1;

            % Create arrow image
            i2 = uiimage(g,"ImageSource",path);
            i2.Layout.Row = 4;
            i2.Layout.Column = 2;

            % Good Case Example 1
            fitFunc4 = @(a,b,y,x) a*x + b*y;
            S4 = sym(fitFunc4);
            chr4 = latex(S4);

            % Express display in uilabel
            l2 = uilabel(g,"HorizontalAlignment",'center');
            l2.Interpreter = 'latex';
            l2.Text = string(['$$',chr4,'$$']); % Get into display mode ($$)
            l2.Layout.Row = 4;
            l2.Layout.Column = 3;
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            prevTab = event.OldValue;

            % Restrict changing tabs by hand. Must change solver
            app.TabGroup2.SelectedTab = prevTab;
        end

        % Value changed function: IterDisplayCheckBox, 
        % ...and 7 other components
        function IterDisplayCheckBoxValueChanged(app, event)
            % value = app.IterDisplayCheckBox.Value;
            src = event.Source;
            value = src.Value;

            if value == 1
                app.Opts = optimoptions(app.Opts,"Display","iter");
            else
                app.Opts = optimoptions(app.Opts,"Display","none");
            end
        end

        % Value changed function: AlgoDropDown, AlgoDropDown_2, 
        % ...and 2 other components
        function AlgoDropDownValueChanged(app, event)
            src = event.Source;
            %             solver = src.Parent.Title;
            value = src.Value;

            %             % Change default values by algo chosen
            %             switch solver
            %                 case 'fmincon'
            %                     if strcmp(value,'interior-point')
            %                         app.MaxFunEvalsEditField.Value = 3000;
            %                         app.MaxIterEditField.Value = 1000;
            %                         app.StepTolEditField.Value = 1e-10;
            %                     else
            %                         app.MaxFunEvalsEditField.Value = 100*app.numP;
            %                         app.MaxIterEditField.Value = 400;
            %                         app.StepTolEditField.Value = 1e-6;
            %                     end
            %
            %                     app.OptimTolEditField.Value = 1e-6;
            %                 case 'fminunc'
            %                     app.MaxFunEvalsEditField_3.Value = 100*app.numP;
            %                     app.MaxIterEditField_3.Value = 400;
            %                     app.OptimTolEditField_3.Value = 1e-6;
            %                     app.StepTolEditField_3.Value = 1e-6;
            %                 case 'lsqnonlin'
            %                     if strcmp(value,'trust-region-reflective')
            %                         app.MaxFunEvalsEditField_2.Value = 100*app.numP;
            %                     else
            %                         app.MaxFunEvalsEditField_2.Value = 200*app.numP;
            %                     end
            %                     app.MaxIterEditField_2.Value = 400;
            %                     app.OptimTolEditField_2.Value = 1e-6;
            %                     app.StepTolEditField_2.Value = 1e-6;
            %                 case 'patternsearch'
            %                     if strcmp(value,'classic')
            %                     elseif strcmp(value,'nups')
            %                     elseif strcmp(value,'nups-gps')
            %                     else
            %
            %                     end
            %
            %             end

            app.Opts = optimoptions(app.Opts,"Algorithm",value);
        end

        % Value changed function: TypicalXCheckBox, TypicalXCheckBox_2, 
        % ...and 1 other component
        function TypicalXCheckBoxValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.TypicalX = value;
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;

            % Get rid of normal solver list
            oldval = app.SolverDropDown.Value;
            app.SolverDropDown.Items = {};
            app.GotoGSTabButton.Enable = "on";

            % Set default solver as fmincon for both GS and MS
            switch selectedButton
                case app.OffButton
                    app.SolverDropDown.Items = app.allSolvers;
                    app.SolverDropDown.Value = oldval;
                    app.GotoGSTabButton.Enable = "off";
                    return

                case app.GlobalSearchButton
                    app.SolverDropDown.Items = {'auto','fmincon'};
                    app.SolverDropDown.Value = 'fmincon';
                    tab = app.globalsearchTab;

                case app.MultiStartButton
                    app.SolverDropDown.Items = {'auto','fmincon','fminunc','lsqnonlin'};
                    app.SolverDropDown.Value = 'fmincon';
                    tab = app.multistartTab;
            end

            % Update new solver and options of new solver value
            SolverDropDownValueChanged(app);

            % Update options once again with tab
            switchTab(app,tab);
        end

        % Button pushed function: GotoGSTabButton
        function GotoGSTabButtonPushed(app, event)
            tab = lower(app.ButtonGroup_2.SelectedObject.Text);
            tab = tab(~isspace(tab));

            % Use solver name to switch tab
            newTab = "app." + tab + "Tab";
            app.TabGroup2.SelectedTab = eval(newTab);

            % Update options with switchTab
            selectedTab = app.TabGroup2.SelectedTab;
            switchTab(app,selectedTab);
        end

        % Drop down opening function: SolverDropDown
        function SolverDropDownOpening(app, event)
            % When solver is opened, allow chance to move from Global
            % Solver tab back to the originally selected Solver dropdown
            % tab
            currentSolve = event.Source.Value;
            currentTab = app.TabGroup2.SelectedTab.Title;

            % If it is auto then we do nothing
            if strcmp(currentSolve,'auto')
                return
            end

            % If the solver name is different than tab name, switch back
            if ~strcmp(currentSolve,currentTab)
                % Use solver name to switch tab
                newTab = "app." + currentSolve + "Tab";
                app.TabGroup2.SelectedTab = eval(newTab);

                % Update options with switchTab
                selectedTab = app.TabGroup2.SelectedTab;
                switchTab(app,selectedTab);
            else
                % Do nothing
            end
        end

        % Value changed function: StartPtsToRunDropDown
        function StartPtsToRunDropDownValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            switch src.Parent.Title
                case 'multistart'
                    app.MS = MultiStart(app.MS,"StartPointsToRun",value);
                case 'globalsearch'
                    app.GS = GlobalSearch(app.GS,"StartPointsToRun",value);
            end
        end

        % Value changed function: IterDisplayCheckBox_4, 
        % ...and 1 other component
        function IterDisplayCheckBox_4ValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            % Depends on which global solver is called
            switch src.Parent.Title
                case 'multistart'
                    if value == 1
                        app.MS = MultiStart(app.MS,"Display","iter");
                    else
                        app.MS = MultiStart(app.MS,"Display","none");
                    end
                case 'globalsearch'
                    if value == 1
                        app.GS = GlobalSearch(app.GS,"Display","iter");
                    else
                        app.GS = GlobalSearch(app.GS,"Display","none");
                    end
            end
        end

        % Value changed function: UseParallelCheckBox
        function UseParallelCheckBoxValueChanged(app, event)
            value = app.UseParallelCheckBox.Value;

            % Depends on which global solver is called
            if value == 1
                app.MS = MultiStart(app.MS,"UseParallel",true);
            else
                app.MS = MultiStart(app.MS,"UseParallel",false);
            end
        end

        % Value changed function: MaxTimeEditField, MaxTimeEditField_2
        function MaxTimeEditFieldValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            % Depends on which global solver is called
            switch src.Parent.Title
                case 'multistart'
                    app.MS = MultiStart(app.MS,"MaxTime",value);
                case 'globalsearch'
                    app.GS = GlobalSearch(app.GS,"MaxTime",value);
            end
        end

        % Value changed function: FunTolEditField, FunTolEditField_2
        function FunTolEditFieldValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            % Depends on which global solver is called
            switch src.Parent.Title
                case 'multistart'
                    app.MS = MultiStart(app.MS,"FunctionTolerance",value);
                case 'globalsearch'
                    app.GS = GlobalSearch(app.GS,"FunctionTolerance",value);
            end
        end

        % Value changed function: XTolEditField, XTolEditField_2
        function XTolEditFieldValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            % Depends on which global solver is called
            switch src.Parent.Title
                case 'multistart'
                    app.MS = MultiStart(app.MS,"XTolerance",value);
                case 'globalsearch'
                    app.GS = GlobalSearch(app.GS,"XTolerance",value);
            end
        end

        % Value changed function: BasinRadiusEditField
        function BasinRadiusEditFieldValueChanged(app, event)
            value = app.BasinRadiusEditField.Value;

            app.GS = GlobalSearch(app.GS,"BasinRadiusFactor",value);
        end

        % Value changed function: DistThresholdEditField
        function DistThresholdEditFieldValueChanged(app, event)
            value = app.DistThresholdEditField.Value;

            app.GS = GlobalSearch(app.GS,"DistanceThresholdFactor",value);
        end

        % Value changed function: PenaltyThresholdEditField
        function PenaltyThresholdEditFieldValueChanged(app, event)
            value = app.PenaltyThresholdEditField.Value;

            app.GS = GlobalSearch(app.GS,"PenaltyThresholdFactor",value);
        end

        % Value changed function: StagePtsEditField
        function StagePtsEditFieldValueChanged(app, event)
            value = app.StagePtsEditField.Value;

            app.GS = GlobalSearch(app.GS,"NumStageOnePoints",value);
        end

        % Value changed function: TrialPtsEditField
        function TrialPtsEditFieldValueChanged(app, event)
            value = app.TrialPtsEditField.Value;

            app.GS = GlobalSearch(app.GS,"NumTrialPoints",value);
        end

        % Value changed function: MaxFunEvalsEditField, 
        % ...and 5 other components
        function MaxFunEvalsEditFieldValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.Opts = optimoptions(app.Opts,"MaxFunctionEvaluations",value);
        end

        % Value changed function: MaxIterEditField, MaxIterEditField_2, 
        % ...and 4 other components
        function MaxIterEditFieldValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.Opts = optimoptions(app.Opts,"MaxIterations",value);
        end

        % Value changed function: StepTolEditField, StepTolEditField_2, 
        % ...and 2 other components
        function StepTolEditFieldValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.Opts = optimoptions(app.Opts,"StepTolerance",value);
        end

        % Value changed function: OptimTolEditField, OptimTolEditField_2, 
        % ...and 1 other component
        function OptimTolEditFieldValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.Opts = optimoptions(app.Opts,"OptimalityTolerance",value);
        end

        % Value changed function: FunTolEditField_3, FunTolEditField_4, 
        % ...and 2 other components
        function FunTolEditField_3ValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.Opts = optimoptions(app.Opts,"FunctionTolerance",value);
        end

        % Value changed function: MaxTimeEditField_3, MaxTimeEditField_4, 
        % ...and 1 other component
        function MaxTimeEditField_4ValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.Opts = optimoptions(app.Opts,"MaxTime",value);
        end

        % Value changed function: ConstraintTolEditField
        function ConstraintTolEditFieldValueChanged(app, event)
            value = app.ConstraintTolEditField.Value;

            app.Opts = optimoptions(app.Opts,"ConstraintTolerance",value);
        end

        % Value changed function: MinSampleDistEditField
        function MinSampleDistEditFieldValueChanged(app, event)
            value = app.MinSampleDistEditField.Value;

            app.Opts = optimoptions(app.Opts,"MinSampleDistance",value);
        end

        % Value changed function: UseParallelCheckBox_2, 
        % ...and 3 other components
        function UseParallelCheckBox_2ValueChanged(app, event)
            % Use event value instead for robustness
            src = event.Source;
            value = src.Value;

            app.Opts = optimoptions(app.Opts,"UseParallel",value);
        end

        % Value changed function: SwarmSizeEditField
        function SwarmSizeEditFieldValueChanged(app, event)
            value = app.SwarmSizeEditField.Value;

            app.Opts = optimoptions(app.Opts,"SwarmSize",value);
        end

        % Value changed function: HybridFcnDropDown, HybridFcnDropDown_2, 
        % ...and 1 other component
        function HybridFcnDropDownValueChanged(app, event)
            value = app.HybridFcnDropDown.Value;

            app.Opts = optimoptions(app.Opts,"HybridFcn",value);
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            value = selectedButton.Text;

            app.Opts = optimoptions(app.Opts,"AnnealingFcn",value);
        end

        % Value changed function: MaxGensEditField
        function MaxGensEditFieldValueChanged(app, event)
            value = app.MaxGensEditField.Value;

            app.Opts = optimoptions(app.Opts,"MaxGenerations",value);
        end

        % Button pushed function: ClearDataButton
        function ClearData(app, event)
            cla(app.UIAxes); % Clears and re-plots

            % Clear Data
            app.Table = table(0,'VariableNames',"XData");
            app.YData = [];
            app.XData = [];
        end

        % Button pushed function: OutputFitResults
        function OutputFitResultsButtonPushed(app, event)
            assignin("base","FitResults", app.vals);
            assignin("base","FitResiduals", app.FitResiduals);
            assignin("base","FitFunctions", app.cfitResult);
        end

        % Selection changed function: XAxisButtonGroup
        function XAxisButtonGroupSelectionChanged(app, event)
            app.XaxisStyle = app.XAxisButtonGroup.SelectedObject.Text;
            set(app.UIAxes,'XScale',app.XaxisStyle)
        end

        % Selection changed function: YAxisButtonGroup
        function YAxisButtonGroupSelectionChanged(app, event)
            app.YaxisStyle = app.YAxisButtonGroup.SelectedObject.Text;
            set(app.UIAxes,'YScale',app.YaxisStyle)
        end

        % Value changed function: FitXmin
        function FitXminValueChanged(app, event)
            xlim(app.UIAxes,[app.FitXmin.Value app.FitXmax.Value])

        end

        % Value changed function: FitXmax
        function FitXmaxValueChanged(app, event)
            xlim(app.UIAxes,[app.FitXmin.Value app.FitXmax.Value])

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GlobalFittingAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {511, 511, 511};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {511, 511};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {317, '1x', 440};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GlobalFittingAppUIFigure = uifigure('Visible', 'off');
            app.GlobalFittingAppUIFigure.Name = 'Global Fitting App';
            app.GlobalFittingAppUIFigure.Icon = fullfile(pathToMLAPP, 'GlobalFittingApp_resources', 'logo.png');
            app.GlobalFittingAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GlobalFittingAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputDataParamsLabel = uilabel(app.LeftPanel);
            app.InputDataParamsLabel.Text = 'Input Data & Params';
            app.FromFileButton = uibutton(app.LeftPanel, 'push');
            app.FromFileButton.ButtonPushedFcn = createCallbackFcn(app, @FromFileButtonPushed, true);
            app.FromFileButton.Text = 'From File';
            app.FromWorkspaceButton = uibutton(app.LeftPanel, 'push');
            app.FromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @FromWorkspaceButtonPushed, true);
            app.FromWorkspaceButton.Text = 'From Workspace';
            app.GlobalSolversLabel = uilabel(app.LeftPanel);
            app.GlobalSolversLabel.Text = 'Global Solvers';
            app.ButtonGroup_2 = uibuttongroup(app.LeftPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.OffButton = uiradiobutton(app.ButtonGroup_2);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.GlobalSearchButton = uiradiobutton(app.ButtonGroup_2);
            app.GlobalSearchButton.Text = 'Global Search';
            app.MultiStartButton = uiradiobutton(app.ButtonGroup_2);
            app.MultiStartButton.Text = 'Multi Start';
            app.OptimizationSettingsLabel = uilabel(app.LeftPanel);
            app.OptimizationSettingsLabel.Text = 'Optimization Settings';
            app.SolverDropDownLabel = uilabel(app.LeftPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.LeftPanel);
            app.SolverDropDown.Items = {'auto', 'fmincon', 'lsqnonlin', 'patternsearch', 'ga', 'particleswarm', 'simulannealbnd'};
            app.SolverDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SolverDropDownOpening, true);
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Value = 'auto';
            app.GotoGSTabButton = uibutton(app.LeftPanel, 'push');
            app.GotoGSTabButton.ButtonPushedFcn = createCallbackFcn(app, @GotoGSTabButtonPushed, true);
            app.GotoGSTabButton.Enable = 'off';
            app.GotoGSTabButton.Tooltip = {'Switch to slected Global Solver'};
            app.GotoGSTabButton.Text = 'Go to GS Tab';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.TabGroup2.Visible = 'off';
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.multistartTab = uitab(app.TabGroup2);
            app.multistartTab.Title = 'multistart';
            app.FunTolLabel = uilabel(app.multistartTab);
            app.FunTolLabel.Text = 'FunTol:';
            app.FunTolEditField = uieditfield(app.multistartTab, 'numeric');
            app.FunTolEditField.Limits = [0 Inf];
            app.FunTolEditField.ValueChangedFcn = createCallbackFcn(app, @FunTolEditFieldValueChanged, true);
            app.FunTolEditField.Value = 1e-06;
            app.IterDisplayCheckBox_4 = uicheckbox(app.multistartTab);
            app.IterDisplayCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBox_4ValueChanged, true);
            app.IterDisplayCheckBox_4.Text = 'Iter Display';
            app.XTolEditFieldLabel = uilabel(app.multistartTab);
            app.XTolEditFieldLabel.Text = 'XTol:';
            app.XTolEditField = uieditfield(app.multistartTab, 'numeric');
            app.XTolEditField.Limits = [0 Inf];
            app.XTolEditField.ValueChangedFcn = createCallbackFcn(app, @XTolEditFieldValueChanged, true);
            app.XTolEditField.Value = 1e-06;
            app.UseParallelCheckBox = uicheckbox(app.multistartTab);
            app.UseParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBoxValueChanged, true);
            app.UseParallelCheckBox.Text = 'Use Parallel';
            app.MaxTimeEditFieldLabel = uilabel(app.multistartTab);
            app.MaxTimeEditFieldLabel.Text = 'MaxTime:';
            app.MaxTimeEditField = uieditfield(app.multistartTab, 'numeric');
            app.MaxTimeEditField.Limits = [1 Inf];
            app.MaxTimeEditField.RoundFractionalValues = 'on';
            app.MaxTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeEditFieldValueChanged, true);
            app.MaxTimeEditField.Value = Inf;
            app.Hyperlink2 = uihyperlink(app.multistartTab);
            app.Hyperlink2.URL = 'https://www.mathworks.com/help/gads/multistart.html#d124e58987';
            app.Hyperlink2.Text = 'Documentation';
            app.StartPtsToRunDropDownLabel = uilabel(app.multistartTab);
            app.StartPtsToRunDropDownLabel.Text = 'StartPtsToRun';
            app.StartPtsToRunDropDown = uidropdown(app.multistartTab);
            app.StartPtsToRunDropDown.Items = {'all', 'bounds', 'bounds-ineqs'};
            app.StartPtsToRunDropDown.ValueChangedFcn = createCallbackFcn(app, @StartPtsToRunDropDownValueChanged, true);
            app.StartPtsToRunDropDown.Value = 'bounds';
            app.globalsearchTab = uitab(app.TabGroup2);
            app.globalsearchTab.Title = 'globalsearch';
            app.FunTolEditField_2Label = uilabel(app.globalsearchTab);
            app.FunTolEditField_2Label.Text = 'FunTol:';
            app.FunTolEditField_2 = uieditfield(app.globalsearchTab, 'numeric');
            app.FunTolEditField_2.Limits = [0 Inf];
            app.FunTolEditField_2.ValueChangedFcn = createCallbackFcn(app, @FunTolEditFieldValueChanged, true);
            app.FunTolEditField_2.Value = 1e-06;
            app.NumStage1PtsLabel = uilabel(app.globalsearchTab);
            app.NumStage1PtsLabel.Text = 'StagePts:';
            app.StagePtsEditField = uieditfield(app.globalsearchTab, 'numeric');
            app.StagePtsEditField.Limits = [1 Inf];
            app.StagePtsEditField.RoundFractionalValues = 'on';
            app.StagePtsEditField.ValueChangedFcn = createCallbackFcn(app, @StagePtsEditFieldValueChanged, true);
            app.StagePtsEditField.Value = 200;
            app.XTolEditField_2Label = uilabel(app.globalsearchTab);
            app.XTolEditField_2Label.Text = 'XTol:';
            app.XTolEditField_2 = uieditfield(app.globalsearchTab, 'numeric');
            app.XTolEditField_2.Limits = [0 Inf];
            app.XTolEditField_2.ValueChangedFcn = createCallbackFcn(app, @XTolEditFieldValueChanged, true);
            app.XTolEditField_2.Value = 1e-06;
            app.TrialPtsEditFieldLabel = uilabel(app.globalsearchTab);
            app.TrialPtsEditFieldLabel.Text = 'TrialPts:';
            app.TrialPtsEditField = uieditfield(app.globalsearchTab, 'numeric');
            app.TrialPtsEditField.Limits = [1 Inf];
            app.TrialPtsEditField.RoundFractionalValues = 'on';
            app.TrialPtsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialPtsEditFieldValueChanged, true);
            app.TrialPtsEditField.Value = 1000;
            app.BasinRadiusLabel = uilabel(app.globalsearchTab);
            app.BasinRadiusLabel.Text = 'BasinRadius:';
            app.BasinRadiusEditField = uieditfield(app.globalsearchTab, 'numeric');
            app.BasinRadiusEditField.Limits = [0 1];
            app.BasinRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @BasinRadiusEditFieldValueChanged, true);
            app.BasinRadiusEditField.Value = 0.2;
            app.MaxTimeEditField_2Label = uilabel(app.globalsearchTab);
            app.MaxTimeEditField_2Label.Text = 'MaxTime:';
            app.MaxTimeEditField_2 = uieditfield(app.globalsearchTab, 'numeric');
            app.MaxTimeEditField_2.Limits = [1 Inf];
            app.MaxTimeEditField_2.RoundFractionalValues = 'on';
            app.MaxTimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxTimeEditFieldValueChanged, true);
            app.MaxTimeEditField_2.Value = Inf;
            app.DistThresholdLabel = uilabel(app.globalsearchTab);
            app.DistThresholdLabel.Text = 'DistThreshold:';
            app.DistThresholdEditField = uieditfield(app.globalsearchTab, 'numeric');
            app.DistThresholdEditField.Limits = [0 Inf];
            app.DistThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DistThresholdEditFieldValueChanged, true);
            app.DistThresholdEditField.Value = 0.75;
            app.IterDisplayCheckBox_5 = uicheckbox(app.globalsearchTab);
            app.IterDisplayCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBox_4ValueChanged, true);
            app.IterDisplayCheckBox_5.Text = 'Iter Display';
            app.PenaltyThresholdEditFieldLabel = uilabel(app.globalsearchTab);
            app.PenaltyThresholdEditFieldLabel.Text = 'PenaltyThreshold:';
            app.PenaltyThresholdEditField = uieditfield(app.globalsearchTab, 'numeric');
            app.PenaltyThresholdEditField.Limits = [0 Inf];
            app.PenaltyThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @PenaltyThresholdEditFieldValueChanged, true);
            app.PenaltyThresholdEditField.Value = 0.75;
            app.Hyperlink = uihyperlink(app.globalsearchTab);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/gads/globalsearch.html#mw_451335dc-aea8-46f0-98be-a853d41a67cf';
            app.Hyperlink.Text = 'Documentation';
            app.StartPtsToRunDropDown_2Label = uilabel(app.globalsearchTab);
            app.StartPtsToRunDropDown_2Label.Text = 'StartPtsToRun';
            app.StartPtsToRunDropDown_2 = uidropdown(app.globalsearchTab);
            app.StartPtsToRunDropDown_2.Items = {'all', 'bounds', 'bounds-ineqs'};
            app.StartPtsToRunDropDown_2.Value = 'bounds';
            app.fminconTab = uitab(app.TabGroup2);
            app.fminconTab.Title = 'fmincon';
            app.AlgoLabel = uilabel(app.fminconTab);
            app.AlgoLabel.Text = 'Algo:';
            app.AlgoDropDown = uidropdown(app.fminconTab);
            app.AlgoDropDown.Items = {'interior-point', 'sqp', 'sqp-legacy', 'active-set'};
            app.AlgoDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgoDropDownValueChanged, true);
            app.AlgoDropDown.Value = 'interior-point';
            app.IterDisplayCheckBox = uicheckbox(app.fminconTab);
            app.IterDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox.Text = 'Iter Display';
            app.MaxFunEvalsLabel = uilabel(app.fminconTab);
            app.MaxFunEvalsLabel.Text = 'MaxFunEvals:';
            app.MaxFunEvalsEditField = uieditfield(app.fminconTab, 'numeric');
            app.MaxFunEvalsEditField.Limits = [1 Inf];
            app.MaxFunEvalsEditField.RoundFractionalValues = 'on';
            app.MaxFunEvalsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxFunEvalsEditFieldValueChanged, true);
            app.MaxFunEvalsEditField.Value = 3000;
            app.TypicalXCheckBox = uicheckbox(app.fminconTab);
            app.TypicalXCheckBox.ValueChangedFcn = createCallbackFcn(app, @TypicalXCheckBoxValueChanged, true);
            app.TypicalXCheckBox.Text = 'TypicalX';
            app.MaxIterLabel = uilabel(app.fminconTab);
            app.MaxIterLabel.Text = 'MaxIter:';
            app.MaxIterEditField = uieditfield(app.fminconTab, 'numeric');
            app.MaxIterEditField.Limits = [1 Inf];
            app.MaxIterEditField.RoundFractionalValues = 'on';
            app.MaxIterEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterEditFieldValueChanged, true);
            app.MaxIterEditField.Value = 1000;
            app.StepTolEditFieldLabel = uilabel(app.fminconTab);
            app.StepTolEditFieldLabel.Text = 'StepTol:';
            app.StepTolEditField = uieditfield(app.fminconTab, 'numeric');
            app.StepTolEditField.Limits = [0 Inf];
            app.StepTolEditField.ValueChangedFcn = createCallbackFcn(app, @StepTolEditFieldValueChanged, true);
            app.StepTolEditField.Value = 1e-10;
            app.OptimTolEditFieldLabel = uilabel(app.fminconTab);
            app.OptimTolEditFieldLabel.Text = 'OptimTol:';
            app.OptimTolEditField = uieditfield(app.fminconTab, 'numeric');
            app.OptimTolEditField.Limits = [0 Inf];
            app.OptimTolEditField.ValueChangedFcn = createCallbackFcn(app, @OptimTolEditFieldValueChanged, true);
            app.OptimTolEditField.Value = 1e-06;
            app.Hyperlink3_4 = uihyperlink(app.fminconTab);
            app.Hyperlink3_4.URL = 'https://www.mathworks.com/help/optim/ug/fmincon.html#busog7r-options';
            app.Hyperlink3_4.Text = 'Documentation';
            app.fminuncTab = uitab(app.TabGroup2);
            app.fminuncTab.Title = 'fminunc';
            app.AlgoDropDown_3Label = uilabel(app.fminuncTab);
            app.AlgoDropDown_3Label.Text = 'Algo:';
            app.AlgoDropDown_3 = uidropdown(app.fminuncTab);
            app.AlgoDropDown_3.Items = {'quasi-newton', 'trust-region'};
            app.AlgoDropDown_3.ValueChangedFcn = createCallbackFcn(app, @AlgoDropDownValueChanged, true);
            app.AlgoDropDown_3.Value = 'quasi-newton';
            app.MaxFunEvalsEditField_3Label = uilabel(app.fminuncTab);
            app.MaxFunEvalsEditField_3Label.Text = 'MaxFunEvals:';
            app.MaxFunEvalsEditField_3 = uieditfield(app.fminuncTab, 'numeric');
            app.MaxFunEvalsEditField_3.Limits = [1 Inf];
            app.MaxFunEvalsEditField_3.RoundFractionalValues = 'on';
            app.MaxFunEvalsEditField_3.ValueChangedFcn = createCallbackFcn(app, @MaxFunEvalsEditFieldValueChanged, true);
            app.MaxFunEvalsEditField_3.Value = 1;
            app.IterDisplayCheckBox_3 = uicheckbox(app.fminuncTab);
            app.IterDisplayCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox_3.Text = 'Iter Display';
            app.MaxIterEditField_3Label = uilabel(app.fminuncTab);
            app.MaxIterEditField_3Label.Text = 'MaxIter:';
            app.MaxIterEditField_3 = uieditfield(app.fminuncTab, 'numeric');
            app.MaxIterEditField_3.Limits = [1 Inf];
            app.MaxIterEditField_3.RoundFractionalValues = 'on';
            app.MaxIterEditField_3.ValueChangedFcn = createCallbackFcn(app, @MaxIterEditFieldValueChanged, true);
            app.MaxIterEditField_3.Value = 400;
            app.TypicalXCheckBox_3 = uicheckbox(app.fminuncTab);
            app.TypicalXCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @TypicalXCheckBoxValueChanged, true);
            app.TypicalXCheckBox_3.Text = 'TypicalX';
            app.StepTolEditField_3Label = uilabel(app.fminuncTab);
            app.StepTolEditField_3Label.Text = 'StepTol:';
            app.StepTolEditField_3 = uieditfield(app.fminuncTab, 'numeric');
            app.StepTolEditField_3.Limits = [0 Inf];
            app.StepTolEditField_3.ValueChangedFcn = createCallbackFcn(app, @StepTolEditFieldValueChanged, true);
            app.StepTolEditField_3.Value = 1e-06;
            app.OptimTolEditField_3Label = uilabel(app.fminuncTab);
            app.OptimTolEditField_3Label.Text = 'OptimTol:';
            app.OptimTolEditField_3 = uieditfield(app.fminuncTab, 'numeric');
            app.OptimTolEditField_3.Limits = [0 Inf];
            app.OptimTolEditField_3.ValueChangedFcn = createCallbackFcn(app, @OptimTolEditFieldValueChanged, true);
            app.OptimTolEditField_3.Value = 1e-06;
            app.Hyperlink3_3 = uihyperlink(app.fminuncTab);
            app.Hyperlink3_3.URL = 'https://www.mathworks.com/help/optim/ug/fminunc.html#butpb7p-options';
            app.Hyperlink3_3.Text = 'Documentation';
            app.lsqnonlinTab = uitab(app.TabGroup2);
            app.lsqnonlinTab.Title = 'lsqnonlin';
            app.AlgoDropDown_2Label = uilabel(app.lsqnonlinTab);
            app.AlgoDropDown_2Label.Text = 'Algo:';
            app.AlgoDropDown_2 = uidropdown(app.lsqnonlinTab);
            app.AlgoDropDown_2.Items = {'trust-region-reflective', 'levenberg-marquardt'};
            app.AlgoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AlgoDropDownValueChanged, true);
            app.AlgoDropDown_2.Value = 'trust-region-reflective';
            app.MaxFunEvalsEditField_2Label = uilabel(app.lsqnonlinTab);
            app.MaxFunEvalsEditField_2Label.Text = 'MaxFunEvals:';
            app.MaxFunEvalsEditField_2 = uieditfield(app.lsqnonlinTab, 'numeric');
            app.MaxFunEvalsEditField_2.Limits = [1 Inf];
            app.MaxFunEvalsEditField_2.RoundFractionalValues = 'on';
            app.MaxFunEvalsEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxFunEvalsEditFieldValueChanged, true);
            app.MaxFunEvalsEditField_2.Value = 1;
            app.IterDisplayCheckBox_2 = uicheckbox(app.lsqnonlinTab);
            app.IterDisplayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox_2.Text = 'Iter Display';
            app.MaxIterEditField_2Label = uilabel(app.lsqnonlinTab);
            app.MaxIterEditField_2Label.Text = 'MaxIter:';
            app.MaxIterEditField_2 = uieditfield(app.lsqnonlinTab, 'numeric');
            app.MaxIterEditField_2.Limits = [1 Inf];
            app.MaxIterEditField_2.RoundFractionalValues = 'on';
            app.MaxIterEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxIterEditFieldValueChanged, true);
            app.MaxIterEditField_2.Value = 400;
            app.TypicalXCheckBox_2 = uicheckbox(app.lsqnonlinTab);
            app.TypicalXCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @TypicalXCheckBoxValueChanged, true);
            app.TypicalXCheckBox_2.Text = 'TypicalX';
            app.StepTolEditField_2Label = uilabel(app.lsqnonlinTab);
            app.StepTolEditField_2Label.Text = 'StepTol:';
            app.StepTolEditField_2 = uieditfield(app.lsqnonlinTab, 'numeric');
            app.StepTolEditField_2.Limits = [0 Inf];
            app.StepTolEditField_2.ValueChangedFcn = createCallbackFcn(app, @StepTolEditFieldValueChanged, true);
            app.StepTolEditField_2.Value = 1e-06;
            app.OptimTolEditField_2Label = uilabel(app.lsqnonlinTab);
            app.OptimTolEditField_2Label.Text = 'OptimTol:';
            app.OptimTolEditField_2 = uieditfield(app.lsqnonlinTab, 'numeric');
            app.OptimTolEditField_2.Limits = [0 Inf];
            app.OptimTolEditField_2.ValueChangedFcn = createCallbackFcn(app, @OptimTolEditFieldValueChanged, true);
            app.OptimTolEditField_2.Value = 1e-06;
            app.Hyperlink3_2 = uihyperlink(app.lsqnonlinTab);
            app.Hyperlink3_2.URL = 'https://www.mathworks.com/help/optim/ug/lsqnonlin.html#buuhch7-options';
            app.Hyperlink3_2.Text = 'Documentation';
            app.patternsearchTab = uitab(app.TabGroup2);
            app.patternsearchTab.Title = 'patternsearch';
            app.AlgoDropDown_4Label = uilabel(app.patternsearchTab);
            app.AlgoDropDown_4Label.Text = 'Algo:';
            app.AlgoDropDown_4 = uidropdown(app.patternsearchTab);
            app.AlgoDropDown_4.Items = {'classic', 'nups ', 'nups-gps', 'nups-mads'};
            app.AlgoDropDown_4.ValueChangedFcn = createCallbackFcn(app, @AlgoDropDownValueChanged, true);
            app.AlgoDropDown_4.Value = 'classic';
            app.IterDisplayCheckBox_6 = uicheckbox(app.patternsearchTab);
            app.IterDisplayCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox_6.Text = 'Iter Display';
            app.MaxFunEvalsEditField_4Label = uilabel(app.patternsearchTab);
            app.MaxFunEvalsEditField_4Label.Text = 'MaxFunEvals:';
            app.MaxFunEvalsEditField_4 = uieditfield(app.patternsearchTab, 'numeric');
            app.MaxFunEvalsEditField_4.Limits = [1 Inf];
            app.MaxFunEvalsEditField_4.RoundFractionalValues = 'on';
            app.MaxFunEvalsEditField_4.ValueChangedFcn = createCallbackFcn(app, @MaxFunEvalsEditFieldValueChanged, true);
            app.MaxFunEvalsEditField_4.Value = 1;
            app.MaxIterEditField_4Label = uilabel(app.patternsearchTab);
            app.MaxIterEditField_4Label.Text = 'MaxIter:';
            app.MaxIterEditField_4 = uieditfield(app.patternsearchTab, 'numeric');
            app.MaxIterEditField_4.Limits = [1 Inf];
            app.MaxIterEditField_4.RoundFractionalValues = 'on';
            app.MaxIterEditField_4.ValueChangedFcn = createCallbackFcn(app, @MaxIterEditFieldValueChanged, true);
            app.MaxIterEditField_4.Value = 1;
            app.StepTolEditField_4Label = uilabel(app.patternsearchTab);
            app.StepTolEditField_4Label.Text = 'StepTol:';
            app.StepTolEditField_4 = uieditfield(app.patternsearchTab, 'numeric');
            app.StepTolEditField_4.Limits = [0 Inf];
            app.StepTolEditField_4.ValueChangedFcn = createCallbackFcn(app, @StepTolEditFieldValueChanged, true);
            app.StepTolEditField_4.Value = 1e-06;
            app.UseParallelCheckBox_2 = uicheckbox(app.patternsearchTab);
            app.UseParallelCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBox_2ValueChanged, true);
            app.UseParallelCheckBox_2.Text = 'Use Parallel';
            app.FunTolEditField_3Label = uilabel(app.patternsearchTab);
            app.FunTolEditField_3Label.Text = 'FunTol:';
            app.FunTolEditField_3 = uieditfield(app.patternsearchTab, 'numeric');
            app.FunTolEditField_3.Limits = [0 Inf];
            app.FunTolEditField_3.ValueChangedFcn = createCallbackFcn(app, @FunTolEditField_3ValueChanged, true);
            app.FunTolEditField_3.Value = 1e-06;
            app.Hyperlink3 = uihyperlink(app.patternsearchTab);
            app.Hyperlink3.URL = 'https://www.mathworks.com/help/gads/patternsearch.html#buxdit7-options';
            app.Hyperlink3.Text = 'Documentation';
            app.gaTab = uitab(app.TabGroup2);
            app.gaTab.Title = 'ga';
            app.IterDisplayCheckBox_7 = uicheckbox(app.gaTab);
            app.IterDisplayCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox_7.Text = 'Iter Display';
            app.UseParallelCheckBox_3 = uicheckbox(app.gaTab);
            app.UseParallelCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBox_2ValueChanged, true);
            app.UseParallelCheckBox_3.Text = 'Use Parallel';
            app.MaxGensEditFieldLabel = uilabel(app.gaTab);
            app.MaxGensEditFieldLabel.Text = 'MaxGens:';
            app.MaxGensEditField = uieditfield(app.gaTab, 'numeric');
            app.MaxGensEditField.Limits = [1 Inf];
            app.MaxGensEditField.RoundFractionalValues = 'on';
            app.MaxGensEditField.ValueChangedFcn = createCallbackFcn(app, @MaxGensEditFieldValueChanged, true);
            app.MaxGensEditField.Value = 1;
            app.Hyperlink3_5 = uihyperlink(app.gaTab);
            app.Hyperlink3_5.URL = 'https://www.mathworks.com/help/gads/ga.html#mw_4a8bfdb9-7c4c-4302-8f47-d260b7a43e26';
            app.Hyperlink3_5.Text = 'Documentation';
            app.FunTolEditField_4Label = uilabel(app.gaTab);
            app.FunTolEditField_4Label.Text = 'FunTol:';
            app.FunTolEditField_4 = uieditfield(app.gaTab, 'numeric');
            app.FunTolEditField_4.Limits = [0 Inf];
            app.FunTolEditField_4.ValueChangedFcn = createCallbackFcn(app, @FunTolEditField_3ValueChanged, true);
            app.FunTolEditField_4.Value = 1e-06;
            app.HybridFcnDropDownLabel = uilabel(app.gaTab);
            app.HybridFcnDropDownLabel.Text = 'HybridFcn:';
            app.HybridFcnDropDown = uidropdown(app.gaTab);
            app.HybridFcnDropDown.Items = {'none', 'patternsearch', 'fmincon'};
            app.HybridFcnDropDown.ValueChangedFcn = createCallbackFcn(app, @HybridFcnDropDownValueChanged, true);
            app.HybridFcnDropDown.Value = 'none';
            app.MaxTimeEditField_4Label = uilabel(app.gaTab);
            app.MaxTimeEditField_4Label.Text = 'MaxTime:';
            app.MaxTimeEditField_4 = uieditfield(app.gaTab, 'numeric');
            app.MaxTimeEditField_4.Limits = [1 Inf];
            app.MaxTimeEditField_4.RoundFractionalValues = 'on';
            app.MaxTimeEditField_4.ValueChangedFcn = createCallbackFcn(app, @MaxTimeEditField_4ValueChanged, true);
            app.MaxTimeEditField_4.Value = Inf;
            app.particleswarmTab = uitab(app.TabGroup2);
            app.particleswarmTab.Title = 'particleswarm';
            app.HybridFcnDropDown_2Label = uilabel(app.particleswarmTab);
            app.HybridFcnDropDown_2Label.Text = 'HybridFcn:';
            app.HybridFcnDropDown_2 = uidropdown(app.particleswarmTab);
            app.HybridFcnDropDown_2.Items = {'none', 'patternsearch', 'fmincon'};
            app.HybridFcnDropDown_2.ValueChangedFcn = createCallbackFcn(app, @HybridFcnDropDownValueChanged, true);
            app.HybridFcnDropDown_2.Value = 'none';
            app.IterDisplayCheckBox_8 = uicheckbox(app.particleswarmTab);
            app.IterDisplayCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox_8.Text = 'Iter Display';
            app.UseParallelCheckBox_4 = uicheckbox(app.particleswarmTab);
            app.UseParallelCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBox_2ValueChanged, true);
            app.UseParallelCheckBox_4.Text = 'Use Parallel';
            app.MaxIterEditField_6Label = uilabel(app.particleswarmTab);
            app.MaxIterEditField_6Label.Text = 'MaxIter:';
            app.MaxIterEditField_6 = uieditfield(app.particleswarmTab, 'numeric');
            app.MaxIterEditField_6.Limits = [1 Inf];
            app.MaxIterEditField_6.RoundFractionalValues = 'on';
            app.MaxIterEditField_6.ValueChangedFcn = createCallbackFcn(app, @MaxIterEditFieldValueChanged, true);
            app.MaxIterEditField_6.Value = 1;
            app.FunTolEditField_5Label = uilabel(app.particleswarmTab);
            app.FunTolEditField_5Label.Text = 'FunTol:';
            app.FunTolEditField_5 = uieditfield(app.particleswarmTab, 'numeric');
            app.FunTolEditField_5.Limits = [0 Inf];
            app.FunTolEditField_5.ValueChangedFcn = createCallbackFcn(app, @FunTolEditField_3ValueChanged, true);
            app.FunTolEditField_5.Value = 1e-06;
            app.Hyperlink3_6 = uihyperlink(app.particleswarmTab);
            app.Hyperlink3_6.URL = 'https://www.mathworks.com/help/gads/particleswarm.html#budidgf-options';
            app.Hyperlink3_6.Text = 'Documentation';
            app.MaxTimeEditField_3Label = uilabel(app.particleswarmTab);
            app.MaxTimeEditField_3Label.Text = 'MaxTime:';
            app.MaxTimeEditField_3 = uieditfield(app.particleswarmTab, 'numeric');
            app.MaxTimeEditField_3.Limits = [1 Inf];
            app.MaxTimeEditField_3.RoundFractionalValues = 'on';
            app.MaxTimeEditField_3.ValueChangedFcn = createCallbackFcn(app, @MaxTimeEditField_4ValueChanged, true);
            app.MaxTimeEditField_3.Value = Inf;
            app.SwarmSizeEditFieldLabel = uilabel(app.particleswarmTab);
            app.SwarmSizeEditFieldLabel.Text = 'SwarmSize:';
            app.SwarmSizeEditField = uieditfield(app.particleswarmTab, 'numeric');
            app.SwarmSizeEditField.Limits = [1 Inf];
            app.SwarmSizeEditField.RoundFractionalValues = 'on';
            app.SwarmSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SwarmSizeEditFieldValueChanged, true);
            app.SwarmSizeEditField.Value = 1;
            app.simulannealbndTab = uitab(app.TabGroup2);
            app.simulannealbndTab.Title = 'simulannealbnd';
            app.MaxIterEditField_7Label = uilabel(app.simulannealbndTab);
            app.MaxIterEditField_7Label.Text = 'MaxIter:';
            app.MaxIterEditField_7 = uieditfield(app.simulannealbndTab, 'numeric');
            app.MaxIterEditField_7.Limits = [1 Inf];
            app.MaxIterEditField_7.RoundFractionalValues = 'on';
            app.MaxIterEditField_7.ValueChangedFcn = createCallbackFcn(app, @MaxIterEditFieldValueChanged, true);
            app.MaxIterEditField_7.Value = Inf;
            app.FunTolEditField_6Label = uilabel(app.simulannealbndTab);
            app.FunTolEditField_6Label.Text = 'FunTol:';
            app.FunTolEditField_6 = uieditfield(app.simulannealbndTab, 'numeric');
            app.FunTolEditField_6.Limits = [0 Inf];
            app.FunTolEditField_6.ValueChangedFcn = createCallbackFcn(app, @FunTolEditField_3ValueChanged, true);
            app.FunTolEditField_6.Value = 1e-06;
            app.MaxTimeEditField_5Label = uilabel(app.simulannealbndTab);
            app.MaxTimeEditField_5Label.Text = 'MaxTime:';
            app.MaxTimeEditField_5 = uieditfield(app.simulannealbndTab, 'numeric');
            app.MaxTimeEditField_5.Limits = [1 Inf];
            app.MaxTimeEditField_5.RoundFractionalValues = 'on';
            app.MaxTimeEditField_5.ValueChangedFcn = createCallbackFcn(app, @MaxTimeEditField_4ValueChanged, true);
            app.MaxTimeEditField_5.Value = Inf;
            app.HybridFcnDropDown_3Label = uilabel(app.simulannealbndTab);
            app.HybridFcnDropDown_3Label.Text = 'HybridFcn:';
            app.HybridFcnDropDown_3 = uidropdown(app.simulannealbndTab);
            app.HybridFcnDropDown_3.Items = {'none', 'patternsearch', 'fmincon'};
            app.HybridFcnDropDown_3.ValueChangedFcn = createCallbackFcn(app, @HybridFcnDropDownValueChanged, true);
            app.HybridFcnDropDown_3.Value = 'none';
            app.IterDisplayCheckBox_9 = uicheckbox(app.simulannealbndTab);
            app.IterDisplayCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox_9.Text = 'Iter Display';
            app.Hyperlink3_7 = uihyperlink(app.simulannealbndTab);
            app.Hyperlink3_7.URL = 'https://www.mathworks.com/help/gads/particleswarm.html#budidgf-options';
            app.Hyperlink3_7.Text = 'Documentation';
            app.ButtonGroup_3 = uibuttongroup(app.simulannealbndTab);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.annealingfastButton = uiradiobutton(app.ButtonGroup_3);
            app.annealingfastButton.Text = 'annealingfast';
            app.annealingfastButton.Value = true;
            app.annealingboltzButton = uiradiobutton(app.ButtonGroup_3);
            app.annealingboltzButton.Text = 'annealingboltz';
            app.MaxFunEvalsEditField_5Label = uilabel(app.simulannealbndTab);
            app.MaxFunEvalsEditField_5Label.Text = 'MaxFunEvals:';
            app.MaxFunEvalsEditField_5 = uieditfield(app.simulannealbndTab, 'numeric');
            app.MaxFunEvalsEditField_5.Limits = [1 Inf];
            app.MaxFunEvalsEditField_5.RoundFractionalValues = 'on';
            app.MaxFunEvalsEditField_5.ValueChangedFcn = createCallbackFcn(app, @MaxFunEvalsEditFieldValueChanged, true);
            app.MaxFunEvalsEditField_5.Value = 1;
            app.surrogateoptTab = uitab(app.TabGroup2);
            app.surrogateoptTab.Title = 'surrogateopt';
            app.MaxFunEvalsEditField_6Label = uilabel(app.surrogateoptTab);
            app.MaxFunEvalsEditField_6Label.Text = 'MaxFunEvals:';
            app.MaxFunEvalsEditField_6 = uieditfield(app.surrogateoptTab, 'numeric');
            app.MaxFunEvalsEditField_6.Limits = [1 Inf];
            app.MaxFunEvalsEditField_6.RoundFractionalValues = 'on';
            app.MaxFunEvalsEditField_6.ValueChangedFcn = createCallbackFcn(app, @MaxFunEvalsEditFieldValueChanged, true);
            app.MaxFunEvalsEditField_6.Value = 1;
            app.IterDisplayCheckBox_10 = uicheckbox(app.surrogateoptTab);
            app.IterDisplayCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @IterDisplayCheckBoxValueChanged, true);
            app.IterDisplayCheckBox_10.Text = 'Iter Display';
            app.UseParallelCheckBox_5 = uicheckbox(app.surrogateoptTab);
            app.UseParallelCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBox_2ValueChanged, true);
            app.UseParallelCheckBox_5.Text = 'Use Parallel';
            app.Hyperlink3_8 = uihyperlink(app.surrogateoptTab);
            app.Hyperlink3_8.URL = 'https://www.mathworks.com/help/gads/particleswarm.html#budidgf-options';
            app.Hyperlink3_8.Text = 'Documentation';
            app.ConstraintTolLabel = uilabel(app.surrogateoptTab);
            app.ConstraintTolLabel.Text = 'ConstraintTol:';
            app.ConstraintTolEditField = uieditfield(app.surrogateoptTab, 'numeric');
            app.ConstraintTolEditField.Limits = [0 Inf];
            app.ConstraintTolEditField.ValueChangedFcn = createCallbackFcn(app, @ConstraintTolEditFieldValueChanged, true);
            app.ConstraintTolEditField.Value = 0.001;
            app.MinSampleDistEditFieldLabel = uilabel(app.surrogateoptTab);
            app.MinSampleDistEditFieldLabel.Text = 'MinSampleDist';
            app.MinSampleDistEditField = uieditfield(app.surrogateoptTab, 'numeric');
            app.MinSampleDistEditField.Limits = [0 Inf];
            app.MinSampleDistEditField.ValueChangedFcn = createCallbackFcn(app, @MinSampleDistEditFieldValueChanged, true);
            app.MinSampleDistEditField.Value = 1e-06;
            app.MaxTimeEditField_6Label = uilabel(app.surrogateoptTab);
            app.MaxTimeEditField_6Label.Text = 'MaxTime';
            app.MaxTimeEditField_6 = uieditfield(app.surrogateoptTab, 'numeric');
            app.MaxTimeEditField_6.Limits = [1 Inf];
            app.MaxTimeEditField_6.RoundFractionalValues = 'on';
            app.MaxTimeEditField_6.Value = Inf;
            app.MODELEXAMPLESButton = uibutton(app.LeftPanel, 'push');
            app.MODELEXAMPLESButton.ButtonPushedFcn = createCallbackFcn(app, @MODELEXAMPLESButtonPushed, true);
            app.MODELEXAMPLESButton.Text = 'MODEL EXAMPLES';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.LeftPanel);
            app.Lamp.Enable = 'off';
            app.ClearDataButton = uibutton(app.LeftPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearData, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.FitXminEditFieldLabel = uilabel(app.LeftPanel);
            app.FitXminEditFieldLabel.Text = 'Fit X min';
            app.FitXmin = uieditfield(app.LeftPanel, 'numeric');
            app.FitXmin.ValueChangedFcn = createCallbackFcn(app, @FitXminValueChanged, true);
            app.FitXmaxLabel = uilabel(app.LeftPanel);
            app.FitXmaxLabel.Text = 'Fit X max';
            app.FitXmax = uieditfield(app.LeftPanel, 'numeric');
            app.FitXmax.ValueChangedFcn = createCallbackFcn(app, @FitXmaxValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.UIAxes = uiaxes(app.FittingTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ModelPresetsDropDownLabel = uilabel(app.FittingTab);
            app.ModelPresetsDropDownLabel.Text = 'Model Presets';
            app.ModelPresetsDropDown = uidropdown(app.FittingTab);
            app.ModelPresetsDropDown.Items = {'Monoexponential', 'Biexponential', 'Triexponential', 'Power Law', 'Monoexponential + Power Law'};
            app.ModelPresetsDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelPresetsDropDownValueChanged, true);
            app.ModelPresetsDropDown.Value = 'Monoexponential';
            app.StartfittingButton = uibutton(app.FittingTab, 'push');
            app.StartfittingButton.ButtonPushedFcn = createCallbackFcn(app, @StartfittingButtonPushed, true);
            app.StartfittingButton.Enable = 'off';
            app.StartfittingButton.Text = 'Start fitting';
            app.EditModelYEditFieldLabel = uilabel(app.FittingTab);
            app.EditModelYEditFieldLabel.Text = 'Edit Model: Y =';
            app.EditModelYEditField = uieditfield(app.FittingTab, 'text');
            app.EditModelYEditField.ValueChangedFcn = createCallbackFcn(app, @EditModelYEditFieldValueChanged, true);
            app.XAxisButtonGroup = uibuttongroup(app.FittingTab);
            app.XAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XAxisButtonGroupSelectionChanged, true);
            app.XAxisButtonGroup.Title = 'X Axis';
            app.LinearButton = uiradiobutton(app.XAxisButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogButton = uiradiobutton(app.XAxisButtonGroup);
            app.LogButton.Text = 'Log';
            app.YAxisButtonGroup = uibuttongroup(app.FittingTab);
            app.YAxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisButtonGroupSelectionChanged, true);
            app.YAxisButtonGroup.Title = 'Y Axis';
            app.LinearButton_2 = uiradiobutton(app.YAxisButtonGroup);
            app.LinearButton_2.Text = 'Linear';
            app.LinearButton_2.Value = true;
            app.LogButton_2 = uiradiobutton(app.YAxisButtonGroup);
            app.LogButton_2.Text = 'Log';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.MathematicalModelLabel = uilabel(app.GridLayout2);
            app.MathematicalModelLabel.Text = 'Mathematical Model';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '';
            app.OptimizationParametersLabel = uilabel(app.GridLayout2);
            app.OptimizationParametersLabel.Text = 'Optimization Parameters';
            app.ParamTable = uitable(app.GridLayout2);
            app.ParamTable.ColumnName = {'Initial Guess'; 'Lower Bound'; 'Upper Bound'; 'Shared'};
            app.ParamTable.RowName = {};
            app.ParamTable.ColumnEditable = true;
            app.ParamTable.CellEditCallback = createCallbackFcn(app, @ParamTableCellEdit, true);
            app.SolTable = uitable(app.GridLayout2);
            app.SolTable.ColumnName = {'Solution Parameters'; 'Solution Values'};
            app.SolTable.RowName = {};
            app.SolTable.Visible = 'off';
            app.OutputFitResults = uibutton(app.GlobalFittingAppUIFigure, 'push');
            app.OutputFitResults.ButtonPushedFcn = createCallbackFcn(app, @OutputFitResultsButtonPushed, true);
            app.OutputFitResults.Enable = 'off';
            app.OutputFitResults.Text = 'Output Fit Results';
            % Show the figure after all components are created
            app.GlobalFittingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GlobalFittingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlobalFittingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlobalFittingAppUIFigure)
        end
    end
end